classdef MCT_Assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SourceDataButtonGroup           matlab.ui.container.ButtonGroup
        GenerateRandomBitsstreamButton  matlab.ui.control.RadioButton
        GenerateBitsstreamusingtextfileButton  matlab.ui.control.RadioButton
        GenerateBitsstreamusingImagefileButton  matlab.ui.control.RadioButton
        GenerateBitsstreamusingAudiofileButton  matlab.ui.control.RadioButton
        ChannelCodingButtonGroup        matlab.ui.container.ButtonGroup
        HammingCodeButton               matlab.ui.control.RadioButton
        BCH12764CodeButton              matlab.ui.control.RadioButton
        ModulationButtonGroup           matlab.ui.container.ButtonGroup
        FSKButton                       matlab.ui.control.RadioButton
        QAMButton                       matlab.ui.control.RadioButton
        PSKButton                       matlab.ui.control.RadioButton
        QAMButton_2                     matlab.ui.control.RadioButton
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        GenerateInputDataButton         matlab.ui.control.Button
        PerformChannelCodingButton      matlab.ui.control.Button
        ModulateandplotgraphsButton     matlab.ui.control.Button
        ReconstructionButtonGroup       matlab.ui.container.ButtonGroup
        ReconstructtextfileButton       matlab.ui.control.RadioButton
        ReconstructImageButton          matlab.ui.control.RadioButton
        ReconstructAudioButton          matlab.ui.control.RadioButton
        ReconstructanddisplayplayfileButton  matlab.ui.control.Button
        DecodereceivedmessageButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateInputDataButton
        function GenerateInputDataButtonPushed(app, event)
            str = app.FilenameEditField.Value;
            GlobalVariables;
            
            if app.GenerateRandomBitsstreamButton.Value == true
                RandomBits;
            end
            
            if app.GenerateBitsstreamusingtextfileButton.Value == true
                Text2Bits(str);
            end
            
            if app.GenerateBitsstreamusingImagefileButton.Value == true
                Img2Bits(str);
            end
            
            if app.GenerateBitsstreamusingAudiofileButton.Value == true
                Audio2Bits(str);
            end
        end

        % Button pushed function: PerformChannelCodingButton
        function PerformChannelCodingButtonPushed(app, event)
            GlobalVariables;
            
            if app.HammingCodeButton.Value == true
                CodeHamming;
            end
            
            if app.BCH12764CodeButton.Value == true
                CodeBCH;
            end
        end

        % Button pushed function: ModulateandplotgraphsButton
        function ModulateandplotgraphsButtonPushed(app, event)
            if app.FSKButton.Value == true
                FSK;
            end
            
            if app.QAMButton.Value == true
                QAM;
            end
            
            if app.PSKButton.Value == true
                PSK;
            end
            
            if app.QAMButton_2.Value == true
                QAM32;
            end
        end

        % Button pushed function: DecodereceivedmessageButton
        function DecodereceivedmessageButtonPushed(app, event)
            if app.HammingCodeButton.Value == true
                DecodeHamming;
            end
            
            if app.BCH12764CodeButton.Value == true
                DecodeBCH;
            end
        end

        % Button pushed function: 
        % ReconstructanddisplayplayfileButton
        function ReconstructanddisplayplayfileButtonPushed(app, event)
            if app.ReconstructtextfileButton.Value == true
                Bits2Text
            end
            
            if app.ReconstructImageButton.Value == true
                Bits2Img;
            end
            
            if app.ReconstructAudioButton.Value == true
                Bits2Audio;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SourceDataButtonGroup = uibuttongroup(app.UIFigure);
            app.SourceDataButtonGroup.Title = 'Source Data';
            app.GenerateRandomBitsstreamButton = uiradiobutton(app.SourceDataButtonGroup);
            app.GenerateRandomBitsstreamButton.Text = 'Generate Random Bits stream';
            app.GenerateRandomBitsstreamButton.Value = true;
            app.GenerateBitsstreamusingtextfileButton = uiradiobutton(app.SourceDataButtonGroup);
            app.GenerateBitsstreamusingtextfileButton.Text = 'Generate Bits stream using text file';
            app.GenerateBitsstreamusingImagefileButton = uiradiobutton(app.SourceDataButtonGroup);
            app.GenerateBitsstreamusingImagefileButton.Text = 'Generate Bits stream using Image file';
            app.GenerateBitsstreamusingAudiofileButton = uiradiobutton(app.SourceDataButtonGroup);
            app.GenerateBitsstreamusingAudiofileButton.Text = 'Generate Bits stream using Audio file';
            app.ChannelCodingButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelCodingButtonGroup.Title = 'Channel Coding';
            app.HammingCodeButton = uiradiobutton(app.ChannelCodingButtonGroup);
            app.HammingCodeButton.Text = '(7,4) Hamming Code';
            app.HammingCodeButton.Value = true;
            app.BCH12764CodeButton = uiradiobutton(app.ChannelCodingButtonGroup);
            app.BCH12764CodeButton.Text = 'BCH (127,64) Code';
            app.ModulationButtonGroup = uibuttongroup(app.UIFigure);
            app.ModulationButtonGroup.Title = 'Modulation';
            app.FSKButton = uiradiobutton(app.ModulationButtonGroup);
            app.FSKButton.Text = '16 FSK';
            app.FSKButton.Value = true;
            app.QAMButton = uiradiobutton(app.ModulationButtonGroup);
            app.QAMButton.Text = '16 QAM';
            app.PSKButton = uiradiobutton(app.ModulationButtonGroup);
            app.PSKButton.Text = '16 PSK';
            app.QAMButton_2 = uiradiobutton(app.ModulationButtonGroup);
            app.QAMButton_2.Text = '32 QAM';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.GenerateInputDataButton = uibutton(app.UIFigure, 'push');
            app.GenerateInputDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateInputDataButtonPushed, true);
            app.GenerateInputDataButton.Text = 'Generate Input Data';
            app.PerformChannelCodingButton = uibutton(app.UIFigure, 'push');
            app.PerformChannelCodingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformChannelCodingButtonPushed, true);
            app.PerformChannelCodingButton.Text = 'Perform Channel Coding';
            app.ModulateandplotgraphsButton = uibutton(app.UIFigure, 'push');
            app.ModulateandplotgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateandplotgraphsButtonPushed, true);
            app.ModulateandplotgraphsButton.Text = 'Modulate and plot graphs';
            app.ReconstructionButtonGroup = uibuttongroup(app.UIFigure);
            app.ReconstructionButtonGroup.Title = 'Reconstruction';
            app.ReconstructtextfileButton = uiradiobutton(app.ReconstructionButtonGroup);
            app.ReconstructtextfileButton.Text = 'Reconstruct text file';
            app.ReconstructtextfileButton.Value = true;
            app.ReconstructImageButton = uiradiobutton(app.ReconstructionButtonGroup);
            app.ReconstructImageButton.Text = 'Reconstruct Image';
            app.ReconstructAudioButton = uiradiobutton(app.ReconstructionButtonGroup);
            app.ReconstructAudioButton.Text = 'Reconstruct Audio';
            app.ReconstructanddisplayplayfileButton = uibutton(app.UIFigure, 'push');
            app.ReconstructanddisplayplayfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructanddisplayplayfileButtonPushed, true);
            app.ReconstructanddisplayplayfileButton.Text = 'Reconstruct and display/play file';
            app.DecodereceivedmessageButton = uibutton(app.UIFigure, 'push');
            app.DecodereceivedmessageButton.ButtonPushedFcn = createCallbackFcn(app, @DecodereceivedmessageButtonPushed, true);
            app.DecodereceivedmessageButton.Text = 'Decode received message';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCT_Assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end